classdef EEG_Processing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InitializationTab               matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        CheckInitializationButton       matlab.ui.control.Button
        ReadytoRunLamp                  matlab.ui.control.Lamp
        ReadytoRunLampLabel             matlab.ui.control.Label
        RecordingSettingsLabel          matlab.ui.control.Label
        DatasetstoAnalyzeTextArea       matlab.ui.control.TextArea
        DatasetstoAnalyzeTextAreaLabel  matlab.ui.control.Label
        EpochWindowUpperLimitEditField  matlab.ui.control.NumericEditField
        EpochWindowUpperLimitEditFieldLabel  matlab.ui.control.Label
        EpochWindowLowerLimitEditField  matlab.ui.control.NumericEditField
        EpochWindowLowerLimitEditFieldLabel  matlab.ui.control.Label
        SessionSpinner                  matlab.ui.control.Spinner
        SessionSpinnerLabel             matlab.ui.control.Label
        TaskNameEditField               matlab.ui.control.EditField
        TaskNameEditFieldLabel          matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        SampleFrequencyHzEditField      matlab.ui.control.NumericEditField
        SampleFrequencyHzEditFieldLabel  matlab.ui.control.Label
        StimFrequencyHzEditField        matlab.ui.control.NumericEditField
        StimFrequencyHzEditFieldLabel   matlab.ui.control.Label
        StimDurationmsEditField         matlab.ui.control.NumericEditField
        StimDurationmsEditFieldLabel    matlab.ui.control.Label
        RootDataDirectoryEditField      matlab.ui.control.EditField
        RootDataDirectoryEditFieldLabel  matlab.ui.control.Label
        PauseBeforeICAButton            matlab.ui.control.StateButton
        SelectFilesButton               matlab.ui.control.Button
        DataSelectionMethodButtonGroup  matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticButton                 matlab.ui.control.RadioButton
        SelectFolderButton              matlab.ui.control.Button
        ImportDataTab                   matlab.ui.container.Tab
        ImportStatusTextArea            matlab.ui.control.TextArea
        ImportStatusLamp                matlab.ui.control.Lamp
        ImportStatusLampLabel           matlab.ui.control.Label
        DatasetsSelectedTextArea        matlab.ui.control.TextArea
        DatasetsSelectedTextAreaLabel   matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
        PreprocessingTab                matlab.ui.container.Tab
        PreprocessingStatusTextArea     matlab.ui.control.TextArea
        SelectFileButton                matlab.ui.control.Button
        ElectrodeLocationMapTextArea    matlab.ui.control.TextArea
        ElectrodeLocationMapTextAreaLabel  matlab.ui.control.Label
        PreprocessingStatusLamp         matlab.ui.control.Lamp
        PreprocessingStatusLabel        matlab.ui.control.Label
        RunPreprocessingButton          matlab.ui.control.Button
        ICATab                          matlab.ui.container.Tab
        ICAStatusTextArea               matlab.ui.control.TextArea
        WarningThiswilltakeawhileEstimatedLabel  matlab.ui.control.Label
        ICAStatusLamp                   matlab.ui.control.Lamp
        ICAStatusLampLabel              matlab.ui.control.Label
        RunICAButton                    matlab.ui.control.Button
        PostICATab                      matlab.ui.container.Tab
        PostICAStatusTextArea           matlab.ui.control.TextArea
        EpochWindowUpperLimitEditField_2  matlab.ui.control.NumericEditField
        EpochWindowUpperLimitEditField_2Label  matlab.ui.control.Label
        EpochWindowLowerLimitEditField_2  matlab.ui.control.NumericEditField
        EpochWindowLowerLimitEditField_2Label  matlab.ui.control.Label
        PostICAStatusLamp               matlab.ui.control.Lamp
        PostICAStatusLampLabel          matlab.ui.control.Label
        ConfirmCheckedComponentsCheckBox  matlab.ui.control.CheckBox
        RunPostICAButton                matlab.ui.control.Button
        TrialRejectionTab               matlab.ui.container.Tab
        ConfirmbadtrialsrejectedCheckBox  matlab.ui.control.CheckBox
        ExportTab                       matlab.ui.container.Tab
        DetailsTab                      matlab.ui.container.Tab
        UpdateLogTextArea               matlab.ui.control.TextArea
        UpdateLogTextAreaLabel          matlab.ui.control.Label
        ReleaseDateTextArea             matlab.ui.control.TextArea
        ReleaseDateTextAreaLabel        matlab.ui.control.Label
        VersionTextArea                 matlab.ui.control.TextArea
        VersionTextAreaLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        RootDirectory % Root Directory for raw data


        sub_ID % subject name. Needed Throughout
        task %task name. Needed Throughout
        sessionNum %session number. Needed Throughout.


        EpochWindow % LL and UL for epoching. Needed post iCA


        filename % name of dataset. Needed Throughout
        filepath % path to dataset. Needed Throughout
        fullpath % full path to dataset. Needed Throughout


        stimDuration % duration of stimulation period. Used in preprocessing
        stimFrequency %frequency for stim period
        samplefreq % sample rate. Needed for preprocessing


        date_analyzed % meta data for when the dataset was processed
        analyzer % meta data to keep track of who processed what

        CoordinateTemplate % Path to coordinate template


        PreAMICA_pause %toggle whether to progress without pausing before ICA
        InitializationStatus % status check if all required variables have been entered. Maybe unused?
        ready2run % check if the data is ready to be run
        ImportComplete %check if import has been completed
        PreprocessComplete
        ICAComplete
        PostICAComplete

        EEG
        ALLEEG
        CURRENTSET
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RootDirectory = pwd;
            app.RootDataDirectoryEditField.Value = app.RootDirectory;
            app.filepath = app.RootDirectory;

            files = dir(fullfile(app.filepath, '*.mat'));   % struct array, one entry per .mat file
            app.filename = {files.name};                % proper cell array of filenames

            app.fullpath = fullfile(app.filepath, app.filename);   % cell array of full paths
            if ~isempty(files)
            app.DatasetstoAnalyzeTextArea.Value = app.fullpath;
            end
            
            
            app.sessionNum=1;
            app.EpochWindow = [-2 3];
            app.EpochWindowLowerLimitEditField.Value = app.EpochWindow(1);
            app.EpochWindowUpperLimitEditField.Value = app.EpochWindow(2);
            app.EpochWindowLowerLimitEditField_2.Value = app.EpochWindow(1);
            app.EpochWindowUpperLimitEditField_2.Value = app.EpochWindow(2);

            app.stimDuration = 500;
            app.StimDurationmsEditField.Value = app.stimDuration;

            app.stimFrequency = 30;
            app.StimFrequencyHzEditField.Value = app.stimFrequency;

            app.samplefreq = 2048;
            app.SampleFrequencyHzEditField.Value = app.samplefreq;

            app.PreAMICA_pause = false;
            app.ImportDataButton.Enable = false;
            app.RunPreprocessingButton.Enable = false;
            app.RunICAButton.Enable = false;
            app.RunPostICAButton.Enable = false;

            ProcessingDir = matlab.desktop.editor.getActiveFilename;
            [InstallFolder,fileVersion,~] = fileparts(ProcessingDir);
            CoordinateTemplate = fullfile(InstallFolder, 'SpesMedica_OTB64_from1005.ced');
            app.CoordinateTemplate = CoordinateTemplate;
            app.ElectrodeLocationMapTextArea.Value = app.CoordinateTemplate;


            Changelogloc = fullfile(InstallFolder, 'HNPL Automated Processing of EEG.txt');
            Changelog=readlines(Changelogloc);

            VersionNumidx = contains(Changelog,'Current Version:');
            VersionNumLine = Changelog(VersionNumidx);
            VersionNum = extractAfter(VersionNumLine, ': ');
            ReleaseDateidx = contains(Changelog,'Most Recent Release Date:');
            ReleaseDateLine = Changelog(ReleaseDateidx);
            ReleaseDate = extractAfter(ReleaseDateLine, ': ');

            app.UpdateLogTextArea.Value = Changelog;
            app.ReleaseDateTextArea.Value = ReleaseDate;
            app.VersionTextArea.Value = VersionNum;

            app.UIFigure.Name = join(["Automated Processing of EEG v" VersionNum],'');


        end

        % Value changed function: RootDataDirectoryEditField
        function RootDataDirectoryEditFieldValueChanged(app, event)
            value = app.RootDataDirectoryEditField.Value;
            app.RootDirectory = value;

            if app.AutomaticButton.Value ==1
                filepath = app.RootDirectory;
                app.filepath = filepath;

                files = dir(fullfile(filepath, '*.mat'));   % struct array, one entry per .mat file
                app.filename = {files.name};                % proper cell array of filenames

                app.fullpath = fullfile(filepath, app.filename);   % cell array of full paths
                app.DatasetstoAnalyzeTextArea.Value = app.fullpath;
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.RootDirectory = uigetdir;
            app.RootDataDirectoryEditField.Value = app.RootDirectory;
            if app.AutomaticButton.Value ==1
                filepath = app.RootDirectory;
app.filepath = filepath;

files = dir(fullfile(filepath, '*.mat'));   % struct array, one entry per .mat file
app.filename = {files.name};                % proper cell array of filenames

app.fullpath = fullfile(filepath, app.filename);   % cell array of full paths
app.DatasetstoAnalyzeTextArea.Value = app.fullpath;
            end
        end

        % Selection changed function: DataSelectionMethodButtonGroup
        function DataSelectionMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataSelectionMethodButtonGroup.SelectedObject;
            if app.ManualButton.Value ==1
            %if selectedButton == "Manual"
                app.SelectFilesButton.Enable = true;
            else
                app.SelectFilesButton.Enable = false;
            end

        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
    [app.filename, app.filepath] = uigetfile('*.mat', '',app.RootDirectory, 'MultiSelect','on');
    app.fullpath = fullfile(app.filepath,app.filename);
    app.DatasetstoAnalyzeTextArea.Value = app.fullpath;

        end

        % Value changed function: StimDurationmsEditField
        function StimDurationmsEditFieldValueChanged(app, event)
            value = app.StimDurationmsEditField.Value;
            app.stimDuration = value;
        end

        % Value changed function: StimFrequencyHzEditField
        function StimFrequencyHzEditFieldValueChanged(app, event)
            value = app.StimFrequencyHzEditField.Value;
            app.stimFrequency=value;
        end

        % Value changed function: SampleFrequencyHzEditField
        function SampleFrequencyHzEditFieldValueChanged(app, event)
            value = app.SampleFrequencyHzEditField.Value;
            app.samplefreq=value;
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            value = app.SubjectIDEditField.Value;
            app.sub_ID = value;
        end

        % Value changed function: TaskNameEditField
        function TaskNameEditFieldValueChanged(app, event)
            value = app.TaskNameEditField.Value;
            app.task = value;
        end

        % Value changed function: SessionSpinner
        function SessionSpinnerValueChanged(app, event)
            value = app.SessionSpinner.Value;
            app.sessionNum = value;
        end

        % Value changed function: EpochWindowLowerLimitEditField
        function EpochWindowLowerLimitEditFieldValueChanged(app, event)
            value = app.EpochWindowLowerLimitEditField.Value;
            app.EpochWindow(1) = value;

            %app.EpochWindowLowerLimitEditField.Value = value;
            app.EpochWindowLowerLimitEditField_2.Value = value;
            
        end

        % Value changed function: EpochWindowUpperLimitEditField
        function EpochWindowUpperLimitEditFieldValueChanged(app, event)
            value = app.EpochWindowUpperLimitEditField.Value;
            app.EpochWindow(2) = value;

            %app.EpochWindowUpperLimitEditField.Value = value;
            app.EpochWindowUpperLimitEditField_2.Value = value;
        end

        % Value changed function: PauseBeforeICAButton
        function PauseBeforeICAButtonValueChanged(app, event)
            value = app.PauseBeforeICAButton.Value;
            app.PreAMICA_pause = value;
            if value == 1
                app.PauseBeforeICAButton.BackgroundColor ='g';
            else
                app.PauseBeforeICAButton.BackgroundColor = 'r';
            end
        end

        % Button pushed function: CheckInitializationButton
        function CheckInitializationButtonPushed(app, event)
            if ~isempty(app.RootDirectory)& ~isempty(app.sub_ID)& ~isempty(app.task)& ~isempty(app.sessionNum)& ~isempty(app.EpochWindow)& ~isempty(app.stimDuration)& ~isempty( app.stimFrequency)& ~isempty( app.samplefreq)& ~isempty(app.filename)
                app.ReadytoRunLamp.Color = 'g';
                app.ready2run = true;
                app.ImportDataButton.Enable = true;
                app.DatasetsSelectedTextArea.Value = app.filename;
                app.ImportStatusTextArea.Value = 'idle';

            else
              app.ReadytoRunLamp.Color = 'r';
              app.ready2run = false;
              beep
            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            app.ImportStatusLamp.Color = 'y';
            app.ImportStatusTextArea.Value = 'running';

            filename = string(app.filename);
            [ALLEEG, EEG, CURRENTSET] = EEG_import(app.filepath, filename, app.sessionNum, app.sub_ID, app.task,app.samplefreq,app.stimDuration);
            app.ALLEEG = ALLEEG;
            app.EEG = EEG;
            app.CURRENTSET = CURRENTSET;
            
            assignin("base","EEG",app.EEG);
            assignin("base", "ALLEEG",app.ALLEEG);
            assignin("base", "CURRENTSET", app.CURRENTSET);

            eeglab redraw
            app.ImportComplete = true;
            app.ImportStatusLamp.Color = 'g';
            app.ImportStatusTextArea.Value = 'complete';

            app.RunPreprocessingButton.Enable = true;
            app.PreprocessingStatusTextArea.Value = 'idle';

            if app.PreAMICA_pause == 1
                [ALLEEG, EEG, CURRENTSET] = EEG_preprocess(ALLEEG, EEG, CURRENTSET, string(app.filename),app.CoordinateTemplate);
                app.ALLEEG = ALLEEG;
                app.EEG = EEG;
                app.CURRENTSET = CURRENTSET;

                assignin("base","EEG",app.EEG);
                assignin("base", "ALLEEG",app.ALLEEG);
                assignin("base", "CURRENTSET", app.CURRENTSET);
                eeglab redraw

                app.PreprocessComplete = true;
                app.PreprocessingStatusLamp.Color = 'g';
                app.PreprocessingStatusTextArea.Value = 'Complete';
                app.RunICAButton.Enable = true;
                
                [ALLEEG, EEG, CURRENTSET] = EEG_ICA(ALLEEG, EEG, CURRENTSET, app.RootDirectory, app.sub_ID, app.task, string(app.filename));
                app.ALLEEG = ALLEEG;
                app.EEG = EEG;
                app.CURRENTSET = CURRENTSET;

                assignin("base","EEG",app.EEG);
                assignin("base", "ALLEEG",app.ALLEEG);
                assignin("base", "CURRENTSET", app.CURRENTSET);
                eeglab redraw
                app.ICAComplete = true;
                app.ICAStatusLamp.Color = 'g';
                app.ICAStatusTextArea.Value = 'complete';

                app.RunPostICAButton.Enable = true;
            end

        end

        % Value changed function: ElectrodeLocationMapTextArea
        function ElectrodeLocationMapTextAreaValueChanged(app, event)
            value = app.ElectrodeLocationMapTextArea.Value;
            app.CoordinateTemplate = value;
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [templateFile, templatePath]=uigetfile({'*.ced'; '*.locs'; '*.sfp'});
            app.CoordinateTemplate=fullfile(templatePath, templateFile);
            app.ElectrodeLocationMapTextArea.Value = app.CoordinateTemplate;
            
        end

        % Button pushed function: RunPreprocessingButton
        function RunPreprocessingButtonPushed(app, event)
            app.PreprocessingStatusLamp.Color = 'y';
            app.PreprocessingStatusTextArea.Value = 'Running';

            ALLEEG = evalin("base", "ALLEEG");
            EEG = evalin("base", "EEG");
            CURRENTSET = evalin("base", "CURRENTSET");


            [ALLEEG, EEG, CURRENTSET] = EEG_preprocess(ALLEEG, EEG, CURRENTSET, string(app.filename),app.CoordinateTemplate);

            app.ALLEEG = ALLEEG;
            app.EEG = EEG;
            app.CURRENTSET = CURRENTSET;
            
            assignin("base","EEG",app.EEG);
            assignin("base", "ALLEEG",app.ALLEEG);
            assignin("base", "CURRENTSET", app.CURRENTSET);
            eeglab redraw

            app.PreprocessComplete = true;
            app.PreprocessingStatusLamp.Color = 'g';
            app.PreprocessingStatusTextArea.Value = 'Complete';
            app.RunICAButton.Enable = true;


        end

        % Button pushed function: RunICAButton
        function RunICAButtonPushed(app, event)
            app.ICAStatusLamp.Color = 'y';
            app.ICAStatusTextArea.Value = 'running';

            ALLEEG = evalin("base", "ALLEEG");
            EEG = evalin("base", "EEG");
            CURRENTSET = evalin("base", "CURRENTSET");
            
            [ALLEEG, EEG, CURRENTSET] = EEG_ICA(ALLEEG, EEG, CURRENTSET, app.RootDirectory, app.sub_ID, app.task, string(app.filename));

            app.ALLEEG = ALLEEG;
            app.EEG = EEG;
            app.CURRENTSET = CURRENTSET;
            
            assignin("base","EEG",app.EEG);
            assignin("base", "ALLEEG",app.ALLEEG);
            assignin("base", "CURRENTSET", app.CURRENTSET);
            eeglab redraw
            app.ICAComplete = true;
            app.ICAStatusLamp.Color = 'g';
            app.ICAStatusTextArea.Value = 'complete';

            app.RunPostICAButton.Enable = true;
        end

        % Button pushed function: RunPostICAButton
        function RunPostICAButtonPushed(app, event)
            app.PostICAStatusLamp.Color = 'y';
            app.PostICAStatusTextArea.Value = 'running';

            ALLEEG = evalin("base", "ALLEEG");
            EEG = evalin("base", "EEG");
            CURRENTSET = evalin("base", "CURRENTSET")

            [ALLEEG EEG CURRENTSET] = EEG_PostICA(ALLEEG, EEG, CURRENTSET,app.sub_ID, app.task,string(app.filename),app.EpochWindow);

            app.ALLEEG = ALLEEG;
            app.EEG = EEG;
            app.CURRENTSET = CURRENTSET;
            
            assignin("base","EEG",app.EEG);
            assignin("base", "ALLEEG",app.ALLEEG);
            assignin("base", "CURRENTSET", app.CURRENTSET);
            eeglab redraw
            app.PostICAComplete = true;
            app.PostICAStatusLamp.Color = 'g';
            app.PostICAStatusTextArea.Value = 'complete';
            app.PostICAComplete = true;

            %app.RunPostICAButton.Enable = true;
        end

        % Value changed function: EpochWindowLowerLimitEditField_2
        function EpochWindowLowerLimitEditField_2ValueChanged(app, event)
            value = app.EpochWindowLowerLimitEditField_2.Value;
            app.EpochWindow(1) = value;

            app.EpochWindowLowerLimitEditField.Value = value;
            %app.EpochWindowLowerLimitEditField_2.Value = value;
        end

        % Value changed function: EpochWindowUpperLimitEditField_2
        function EpochWindowUpperLimitEditField_2ValueChanged(app, event)
            value = app.EpochWindowUpperLimitEditField_2.Value;
            app.EpochWindow(1) = value;

            app.EpochWindowUpperLimitEditField.Value = value;
            %app.EpochWindowUpperLimitEditField_2.Value = value;
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.GridLayout = uigridlayout(app.InitializationTab);
            app.SelectFolderButton = uibutton(app.GridLayout, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.DataSelectionMethodButtonGroup = uibuttongroup(app.GridLayout);
            app.DataSelectionMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSelectionMethodButtonGroupSelectionChanged, true);
            app.DataSelectionMethodButtonGroup.Title = 'Data Selection Method';
            app.AutomaticButton = uiradiobutton(app.DataSelectionMethodButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.ManualButton = uiradiobutton(app.DataSelectionMethodButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.SelectFilesButton = uibutton(app.GridLayout, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Enable = 'off';
            app.SelectFilesButton.Text = 'Select Files';
            app.PauseBeforeICAButton = uibutton(app.GridLayout, 'state');
            app.PauseBeforeICAButton.ValueChangedFcn = createCallbackFcn(app, @PauseBeforeICAButtonValueChanged, true);
            app.PauseBeforeICAButton.Text = 'Pause Before ICA';
            app.RootDataDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.RootDataDirectoryEditFieldLabel.Text = 'Root Data Directory';
            app.RootDataDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.RootDataDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @RootDataDirectoryEditFieldValueChanged, true);
            app.RootDataDirectoryEditField.Tooltip = {'Root path to save data. Default to active folder and used for automatic selection'};
            app.StimDurationmsEditFieldLabel = uilabel(app.GridLayout);
            app.StimDurationmsEditFieldLabel.Text = 'Stim Duration (ms)';
            app.StimDurationmsEditField = uieditfield(app.GridLayout, 'numeric');
            app.StimDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @StimDurationmsEditFieldValueChanged, true);
            app.StimDurationmsEditField.Value = 500;
            app.StimFrequencyHzEditFieldLabel = uilabel(app.GridLayout);
            app.StimFrequencyHzEditFieldLabel.Text = 'Stim Frequency (Hz)';
            app.StimFrequencyHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.StimFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @StimFrequencyHzEditFieldValueChanged, true);
            app.StimFrequencyHzEditField.Value = 30;
            app.SampleFrequencyHzEditFieldLabel = uilabel(app.GridLayout);
            app.SampleFrequencyHzEditFieldLabel.Text = 'Sample Frequency (Hz)';
            app.SampleFrequencyHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleFrequencyHzEditFieldValueChanged, true);
            app.SampleFrequencyHzEditField.Value = 2048;
            app.SubjectIDEditFieldLabel = uilabel(app.GridLayout);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.GridLayout, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.TaskNameEditFieldLabel = uilabel(app.GridLayout);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.TaskNameEditField = uieditfield(app.GridLayout, 'text');
            app.TaskNameEditField.ValueChangedFcn = createCallbackFcn(app, @TaskNameEditFieldValueChanged, true);
            app.SessionSpinnerLabel = uilabel(app.GridLayout);
            app.SessionSpinnerLabel.Text = 'Session #';
            app.SessionSpinner = uispinner(app.GridLayout);
            app.SessionSpinner.Limits = [1 Inf];
            app.SessionSpinner.ValueChangedFcn = createCallbackFcn(app, @SessionSpinnerValueChanged, true);
            app.SessionSpinner.Value = 1;
            app.EpochWindowLowerLimitEditFieldLabel = uilabel(app.GridLayout);
            app.EpochWindowLowerLimitEditFieldLabel.Text = 'Epoch Window Lower Limit';
            app.EpochWindowLowerLimitEditField = uieditfield(app.GridLayout, 'numeric');
            app.EpochWindowLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @EpochWindowLowerLimitEditFieldValueChanged, true);
            app.EpochWindowUpperLimitEditFieldLabel = uilabel(app.GridLayout);
            app.EpochWindowUpperLimitEditFieldLabel.Text = 'Epoch Window Upper Limit';
            app.EpochWindowUpperLimitEditField = uieditfield(app.GridLayout, 'numeric');
            app.EpochWindowUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @EpochWindowUpperLimitEditFieldValueChanged, true);
            app.DatasetstoAnalyzeTextAreaLabel = uilabel(app.GridLayout);
            app.DatasetstoAnalyzeTextAreaLabel.Text = 'Datasets to Analyze';
            app.DatasetstoAnalyzeTextArea = uitextarea(app.GridLayout);
            app.DatasetstoAnalyzeTextArea.Editable = 'off';
            app.RecordingSettingsLabel = uilabel(app.GridLayout);
            app.RecordingSettingsLabel.Text = 'Recording Settings';
            app.ReadytoRunLampLabel = uilabel(app.GridLayout);
            app.ReadytoRunLampLabel.Text = 'Ready to Run?';
            app.ReadytoRunLamp = uilamp(app.GridLayout);
            app.CheckInitializationButton = uibutton(app.GridLayout, 'push');
            app.CheckInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @CheckInitializationButtonPushed, true);
            app.CheckInitializationButton.Text = 'Check Initialization';
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Title = 'Import Data';
            app.ImportDataButton = uibutton(app.ImportDataTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.DatasetsSelectedTextAreaLabel = uilabel(app.ImportDataTab);
            app.DatasetsSelectedTextAreaLabel.Text = 'Datasets Selected';
            app.DatasetsSelectedTextArea = uitextarea(app.ImportDataTab);
            app.DatasetsSelectedTextArea.Editable = 'off';
            app.ImportStatusLampLabel = uilabel(app.ImportDataTab);
            app.ImportStatusLampLabel.Text = 'Import Status';
            app.ImportStatusLamp = uilamp(app.ImportDataTab);
            app.ImportStatusTextArea = uitextarea(app.ImportDataTab);
            app.ImportStatusTextArea.Editable = 'off';
            app.ImportStatusTextArea.Value = {'Initialization Incomplete'};
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.RunPreprocessingButton = uibutton(app.PreprocessingTab, 'push');
            app.RunPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunPreprocessingButtonPushed, true);
            app.RunPreprocessingButton.Text = 'Run Preprocessing';
            app.PreprocessingStatusLabel = uilabel(app.PreprocessingTab);
            app.PreprocessingStatusLabel.Text = 'Preprocessing Status';
            app.PreprocessingStatusLamp = uilamp(app.PreprocessingTab);
            app.ElectrodeLocationMapTextAreaLabel = uilabel(app.PreprocessingTab);
            app.ElectrodeLocationMapTextAreaLabel.Text = 'Electrode Location Map';
            app.ElectrodeLocationMapTextArea = uitextarea(app.PreprocessingTab);
            app.ElectrodeLocationMapTextArea.ValueChangedFcn = createCallbackFcn(app, @ElectrodeLocationMapTextAreaValueChanged, true);
            app.SelectFileButton = uibutton(app.PreprocessingTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.PreprocessingStatusTextArea = uitextarea(app.PreprocessingTab);
            app.PreprocessingStatusTextArea.Editable = 'off';
            app.PreprocessingStatusTextArea.Value = {'Import Incomplete'};
            app.ICATab = uitab(app.TabGroup);
            app.ICATab.Title = 'ICA';
            app.RunICAButton = uibutton(app.ICATab, 'push');
            app.RunICAButton.ButtonPushedFcn = createCallbackFcn(app, @RunICAButtonPushed, true);
            app.RunICAButton.Text = 'Run ICA';
            app.ICAStatusLampLabel = uilabel(app.ICATab);
            app.ICAStatusLampLabel.Text = 'ICA Status';
            app.ICAStatusLamp = uilamp(app.ICATab);
            app.WarningThiswilltakeawhileEstimatedLabel = uilabel(app.ICATab);
            app.WarningThiswilltakeawhileEstimatedLabel.Text = 'Warning: This will take a while. Estimated processing time is ~30 minutes for every 5 minutes of data.';
            app.ICAStatusTextArea = uitextarea(app.ICATab);
            app.ICAStatusTextArea.Editable = 'off';
            app.ICAStatusTextArea.Value = {'Preprocessing Incomplete'};
            app.PostICATab = uitab(app.TabGroup);
            app.PostICATab.Title = 'Post ICA';
            app.RunPostICAButton = uibutton(app.PostICATab, 'push');
            app.RunPostICAButton.ButtonPushedFcn = createCallbackFcn(app, @RunPostICAButtonPushed, true);
            app.RunPostICAButton.Text = 'Run Post ICA';
            app.ConfirmCheckedComponentsCheckBox = uicheckbox(app.PostICATab);
            app.ConfirmCheckedComponentsCheckBox.Text = 'Confirm Checked Components';
            app.PostICAStatusLampLabel = uilabel(app.PostICATab);
            app.PostICAStatusLampLabel.Text = 'Post ICA Status';
            app.PostICAStatusLamp = uilamp(app.PostICATab);
            app.EpochWindowLowerLimitEditField_2Label = uilabel(app.PostICATab);
            app.EpochWindowLowerLimitEditField_2Label.Text = 'Epoch Window Lower Limit';
            app.EpochWindowLowerLimitEditField_2 = uieditfield(app.PostICATab, 'numeric');
            app.EpochWindowLowerLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @EpochWindowLowerLimitEditField_2ValueChanged, true);
            app.EpochWindowUpperLimitEditField_2Label = uilabel(app.PostICATab);
            app.EpochWindowUpperLimitEditField_2Label.Text = 'Epoch Window Upper Limit';
            app.EpochWindowUpperLimitEditField_2 = uieditfield(app.PostICATab, 'numeric');
            app.EpochWindowUpperLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @EpochWindowUpperLimitEditField_2ValueChanged, true);
            app.PostICAStatusTextArea = uitextarea(app.PostICATab);
            app.PostICAStatusTextArea.Editable = 'off';
            app.PostICAStatusTextArea.Value = {'ICA Incomplete'};
            app.TrialRejectionTab = uitab(app.TabGroup);
            app.TrialRejectionTab.Title = 'Trial Rejection';
            app.ConfirmbadtrialsrejectedCheckBox = uicheckbox(app.TrialRejectionTab);
            app.ConfirmbadtrialsrejectedCheckBox.Text = 'Confirm bad trials rejected';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.VersionTextAreaLabel = uilabel(app.DetailsTab);
            app.VersionTextAreaLabel.Text = 'Version';
            app.VersionTextArea = uitextarea(app.DetailsTab);
            app.VersionTextArea.Editable = 'off';
            app.VersionTextArea.Value = {'ERROR'};
            app.ReleaseDateTextAreaLabel = uilabel(app.DetailsTab);
            app.ReleaseDateTextAreaLabel.Text = 'Release Date';
            app.ReleaseDateTextArea = uitextarea(app.DetailsTab);
            app.ReleaseDateTextArea.Editable = 'off';
            app.ReleaseDateTextArea.Value = {'ERROR'};
            app.UpdateLogTextAreaLabel = uilabel(app.DetailsTab);
            app.UpdateLogTextAreaLabel.Text = 'Update Log';
            app.UpdateLogTextArea = uitextarea(app.DetailsTab);
            app.UpdateLogTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Processing_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end